classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConfigNNTab                     matlab.ui.container.Tab
        NumoflayersSpinnerLabel         matlab.ui.control.Label
        NumoflayersSpinner              matlab.ui.control.Spinner
        TrainfunctionDropDownLabel      matlab.ui.control.Label
        TrainfunctionDropDown           matlab.ui.control.DropDown
        PerformancefunctionDropDownLabel  matlab.ui.control.Label
        PerformancefunctionDropDown     matlab.ui.control.DropDown
        StopconditionsPanel             matlab.ui.container.Panel
        epochsEditFieldLabel            matlab.ui.control.Label
        epochsEditField                 matlab.ui.control.EditField
        min_gradEditFieldLabel          matlab.ui.control.Label
        min_gradEditField               matlab.ui.control.EditField
        validationsEditFieldLabel       matlab.ui.control.Label
        validationsEditField            matlab.ui.control.EditField
        timeEditFieldLabel              matlab.ui.control.Label
        timeEditField                   matlab.ui.control.EditField
        AdvanceconfigurationPanel       matlab.ui.container.Panel
        learningrateEditFieldLabel      matlab.ui.control.Label
        learningrateEditField           matlab.ui.control.EditField
        momentumEditField_2Label        matlab.ui.control.Label
        momentumEditField               matlab.ui.control.EditField
        lambdaEditFieldLabel            matlab.ui.control.Label
        lambdaEditField                 matlab.ui.control.EditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.EditField
        CreateButton                    matlab.ui.control.Button
        LayerstranferfunctionPanel      matlab.ui.container.Panel
        Layer1DropDown_7Label           matlab.ui.control.Label
        Layer1DropDown_1                matlab.ui.control.DropDown
        NumberofneuronsLabel            matlab.ui.control.Label
        Layer1_size                     matlab.ui.control.NumericEditField
        Layer2_size                     matlab.ui.control.NumericEditField
        Layer3_size                     matlab.ui.control.NumericEditField
        Layer2DropDownLabel             matlab.ui.control.Label
        Layer2DropDown                  matlab.ui.control.DropDown
        Layer3DropDownLabel             matlab.ui.control.Label
        Layer3DropDown                  matlab.ui.control.DropDown
        Layer4_size                     matlab.ui.control.NumericEditField
        Layer4DropDownLabel             matlab.ui.control.Label
        Layer4DropDown                  matlab.ui.control.DropDown
        DividefunctionDropDownLabel     matlab.ui.control.Label
        DividefunctionDropDown          matlab.ui.control.DropDown
        ModifyparmsButton               matlab.ui.control.Button
        trainRatio                      matlab.ui.control.NumericEditField
        valRatio                        matlab.ui.control.NumericEditField
        testRatio                       matlab.ui.control.NumericEditField
        TrainRatioLabel                 matlab.ui.control.Label
        ValRatioLabel                   matlab.ui.control.Label
        TestRatioLabel                  matlab.ui.control.Label
        TrainnetworkTab                 matlab.ui.container.Tab
        TrainButton                     matlab.ui.control.Button
        ResultsPanel                    matlab.ui.container.Panel
        pTest                           matlab.ui.control.NumericEditField
        PrecisotreinoLabel              matlab.ui.control.Label
        PrecisotesteLabel               matlab.ui.control.Label
        pTrain                          matlab.ui.control.NumericEditField
        bestTrainPerf                   matlab.ui.control.NumericEditField
        MelhorperformancetreinoLabel    matlab.ui.control.Label
        bestPerfVal                     matlab.ui.control.NumericEditField
        MelhorperformancevalidaoLabel   matlab.ui.control.Label
        bestPerfTest                    matlab.ui.control.NumericEditField
        MelhorperformancetesteLabel     matlab.ui.control.Label
        MelhopocaLabel                  matlab.ui.control.Label
        bestEpoch                       matlab.ui.control.NumericEditField
        TempoLabel                      matlab.ui.control.Label
        time                            matlab.ui.control.NumericEditField
        perf                            matlab.ui.control.NumericEditField
        PerformanceLabel                matlab.ui.control.Label
        SavenetButton                   matlab.ui.control.Button
        SimulateTab                     matlab.ui.container.Tab
        LoadimageButton                 matlab.ui.control.Button
        SimulateButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        Label_2                         matlab.ui.control.Label
        NomedaespcieEditFieldLabel      matlab.ui.control.Label
        NomedaespcieEditField           matlab.ui.control.EditField
        PrecisoLabel                    matlab.ui.control.Label
        precisao                        matlab.ui.control.EditField
        ImageFeaturesTab                matlab.ui.container.Tab
        TrainButton_2                   matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        NomedaespcieEditField_3Label    matlab.ui.control.Label
        NomedaespcieEditField_3         matlab.ui.control.EditField
        ExtractfeaturesButton           matlab.ui.control.Button
        featuresPanel                   matlab.ui.container.Panel
        height                          matlab.ui.control.NumericEditField
        widthLabel                      matlab.ui.control.Label
        heightLabel                     matlab.ui.control.Label
        width                           matlab.ui.control.NumericEditField
        area                            matlab.ui.control.NumericEditField
        AreaLabel                       matlab.ui.control.Label
        corners                         matlab.ui.control.NumericEditField
        CornersLabel                    matlab.ui.control.Label
        perimeter                       matlab.ui.control.NumericEditField
        PerimeterLabel                  matlab.ui.control.Label
        ori                             matlab.ui.control.NumericEditField
        OrientationLabel                matlab.ui.control.Label
        SimulateButton_2                matlab.ui.control.Button
        ImagefiltersButtonGroup         matlab.ui.container.ButtonGroup
        averageButton                   matlab.ui.control.RadioButton
        gaussianButton                  matlab.ui.control.RadioButton
        medianButton                    matlab.ui.control.RadioButton
        guided_filterButton             matlab.ui.control.RadioButton
        originalButton                  matlab.ui.control.RadioButton
        SavenetButton_2                 matlab.ui.control.Button
        LoadimageButton_2               matlab.ui.control.Button
        PrecisotreinoEditFieldLabel     matlab.ui.control.Label
        precisao_img_treino             matlab.ui.control.NumericEditField
        PrecisotesteEditFieldLabel      matlab.ui.control.Label
        precisao_img_test               matlab.ui.control.NumericEditField
        RealTimeTab                     matlab.ui.container.Tab
        UIAxes_video                    matlab.ui.control.UIAxes
        StartDetectionButton            matlab.ui.control.Button
        VideoSourceButtonGroup          matlab.ui.container.ButtonGroup
        laptopcamButton                 matlab.ui.control.RadioButton
        usbcamButton                    matlab.ui.control.RadioButton
        rpicamButton                    matlab.ui.control.RadioButton
        NomedaespcieEditField_2Label    matlab.ui.control.Label
        NomedaespcieEditField_2         matlab.ui.control.EditField
        IPEditFieldLabel                matlab.ui.control.Label
        IPEditField                     matlab.ui.control.EditField
        StopButton                      matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        CurrentnetworkEditFieldLabel    matlab.ui.control.Label
        CurrentnetworkEditField         matlab.ui.control.EditField
        LoaddatasetButton               matlab.ui.control.Button
        DatasetEditFieldLabel           matlab.ui.control.Label
        DatasetEditField                matlab.ui.control.EditField
        AvailabledatasetsDropDownLabel  matlab.ui.control.Label
        AvailabledatasetsDropDown       matlab.ui.control.DropDown
        TargetEditFieldLabel            matlab.ui.control.Label
        TargetEditField                 matlab.ui.control.EditField
        TargetsDropDownLabel            matlab.ui.control.Label
        TargetsDropDown                 matlab.ui.control.DropDown
        LoadNetButton                   matlab.ui.control.Button
    end


    properties (Access = public)
        pathToXls = '.\DataSet\ClassificaccaoFolhas.xlsx'; % Description
    end


    properties (Access = public)
        dataInputFeatures % Description
    end
    properties (Access = public)
        imgSetActive % Description
    end
    
    properties (Access = public)
        loadImage = false; % Description
    end
    
    properties (Access = public)
        dataInput % Description
        end
    properties (Access = public)
        dataTargetBin % Description
    end
    properties (Access = public)
        camLaptop = true;
    end
    properties (Access = public)
        activeNet % Description
    end
    properties (Access = public)
        simOut % Description
     end
    properties (Access = public)
        tr % Description
     end
      properties (Access = public)
        numLayers = 1 % Description
    end
    properties (Access = public)
        divideFcn% Description
    end
    properties (Access = public)
        trainFcn% Description
    end
    
    properties (Access = public)
        g_valRatio = 0.0% Description
        end
    properties (Access = public)
        g_testRatio = 0.0% Description
    end
    properties (Access = public)
        g_trainRatio = 0.0% Description
    end

      properties (Access = public)
        layerSize % Description
    end

      properties (Access = public)
        layer2_size = 10% Description
    end

    properties (Access = public)
        layerFnc % Description
    end
    
    properties (Access = public)
        layersFnc = "" % Description
    end

    properties (Access = public)
         camOn = true % Description
    end
    

    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            
             
            app.numLayers =  app.NumoflayersSpinner.Value;
            app.trainFcn = app.TrainfunctionDropDown.Value;
            
            
            if strcmp(app.DividefunctionDropDown.Value, 'none')
                 app.divideFcn = '';
            else
                 app.divideFcn = app.DividefunctionDropDown.Value;
            end


            app.g_trainRatio = app.trainRatio.Value;
            app.g_valRatio = app.valRatio.Value;
            app.g_testRatio = app.testRatio.Value;
                        
            
            if app.numLayers == 1
                app.layersFnc(1) = app.Layer1DropDown_1.Value;
                app.layersFnc(2) = app.Layer2DropDown.Value;
                app.layerSize(1) = app.Layer1_size.Value;
            
            elseif app.numLayers == 2
                app.layersFnc(1) = app.Layer1DropDown_1.Value;
                app.layersFnc(2) = app.Layer2DropDown.Value;
                app.layersFnc(3) = app.Layer3DropDown.Value;
                app.layerSize(1) = app.Layer1_size.Value;
                app.layerSize(2) = app.Layer2_size.Value;
            
            elseif app.numLayers == 3
                app.layersFnc(1) = app.Layer1DropDown_1.Value;
                app.layersFnc(2) = app.Layer2DropDown.Value;
                app.layersFnc(3) = app.Layer3DropDown.Value;
                app.layerSize(1) = app.Layer1_size.Value;
                app.layerSize(2) = app.Layer2_size.Value;
                app.layerSize(3) = app.Layer3_size.Value;
            end
            
            disp(length(app.layersFnc));
            disp(app.layerSize);
            
            
           try
            
                %configure network
                app.activeNet = initNN(app.layersFnc, app.layerSize);
                configNNTrain(app.activeNet, app.trainFcn, app.divideFcn, app.g_trainRatio, app.g_valRatio, app.g_testRatio);
                
                app.activeNet.trainFcn =app.trainFcn;
                
                disp(app.trainFcn);
                
                 disp(app.activeNet);
                 view(app.activeNet);
                
               catch exp
          
                        uialert(app.UIFigure,'invalid parms','Invalid neural network config');
                        disp(exp)
                        return;
    
               end
           
             %advance configuration
            app.activeNet.trainParam.epochs         = str2double(app.epochsEditField.Value);
            app.activeNet.trainParam.goal           = 0;
            app.activeNet.trainParam.max_fail       = str2double(app.validationsEditField.Value);
            app.activeNet.trainParam.min_grad       = str2double(app.min_gradEditField.Value);
            app.activeNet.trainParam.show           = 25;
            app.activeNet.trainParam.showCommandLine= false; 
            app.activeNet.trainParam.showWindow     = true;
            app.activeNet.trainParam.time           = str2double(app.timeEditField.Value);
            
             if strcmp(app.trainFcn, 'traingdx')
                app.activeNet.trainParam.lr             = str2double(app.learningrateEditField.Value); % Learning rate % good results 0.0001;
                
                %CHECK  0 to 1
                app.activeNet.trainParam.mc             = str2double(app.momentumEditField.Value);
             end
            if strcmp(app.trainFcn, 'trainscg')
                app.activeNet.trainParam.sigma          = str2double(app.lambdaEditField.Value); % 5.0e-5
                app.activeNet.trainParam.lambda         = str2double(app.sigmaEditField.Value); % 5.0e-7
            end
            
           app.TabGroup.SelectedTab = app.TrainnetworkTab;
          % disp(app.layersFnc);
           app.CurrentnetworkEditField.Value = ['net_', app.trainFcn, ];  
            
            
        end

        % Value changed function: DividefunctionDropDown
        function DividefunctionDropDownValueChanged(app, event)
            value = app.DividefunctionDropDown.Value;
            
            if ~strcmp(value, 'none')
                app.trainRatio.Enable = 'on';
                app.valRatio.Enable = 'on';
                app.testRatio.Enable = 'on';
            else
                app.trainRatio.Enable = 'off';
                app.valRatio.Enable = 'off';
                app.testRatio.Enable = 'off';  
            end
            
        end

        % Button pushed function: ExtractfeaturesButton
        function ExtractfeaturesButtonPushed(app, event)
            img = app.imgSetActive;
            
            
        if ~app.loadImage
            uialert(app.UIFigure,'Image not found','Invalid data ');
            return
        end
                    
            [B,~] = bwboundaries(img.binData,'noholes'); 
            B = B{1,1};
            
            aux = regionprops(img.binData(:), 'Perimeter', 'Area', 'Orientation');
            img.orientation  = round(abs(aux.Orientation));
            img.Area         = round(aux.Area);
            img.Perimeter    = round( aux.Perimeter);
            img.width        = max(B(:,1)) - min(B(:,1));
            img.height       = max(B(:,2)) - min(B(:,2));
            %             img.avgBoarderX  = round(mean(B(:,1)));
            %             img.avgBoarderY  = round( mean(B(:,2)));
            %             
            
            points = detectHarrisFeatures(img.binData);
            img.corners = points.Count;
            
            app.width.Value = img.width; 
            app.height.Value = img.height;
            app.area.Value = img.Area;
            app.corners.Value = img.corners;
            app.perimeter.Value = img.Perimeter;
            app.ori.Value = img.orientation;
           
        end

        % Selection changed function: ImagefiltersButtonGroup
        function ImagefiltersButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImagefiltersButtonGroup.SelectedObject;
            
            
            disp(selectedButton.Text);
                        
            
            img = app.imgSetActive.original;
            imgR = imread(img);
            
             if app.loadImage == true
           
                 if strcmp(selectedButton.Text, 'original')
                     
                    imshow(app.imgSetActive.original,'Parent',app.UIAxes_2);  
                     
                 elseif strcmp(selectedButton.Text, 'resize')
                     
                    merge = zeros(87);
                    A_bin_resize =  imbinarize(app.imgSetActive.binData, 0.05);
                    [y, x] = size(A_bin_resize);
                    startX = round((87/2)-(x/2));
                    startY = round((87/2)-(y/2));
                    merge(1:y, 1:x) = A_bin_resize;
                   % merge(startY: startY + y - 1, startX : startX + x - 1) = A_bin_resize;
                     
                    imshow(merge,'Parent',app.UIAxes_2); 
                     
                 elseif strcmp(selectedButton.Text, 'border')
                     
                     [B,~] = bwboundaries(app.imgSetActive.binData,'noholes'); 
      
                     disp(B)
                     
                    imshow(imgR,'Parent',app.UIAxes_2); 


                 elseif strcmp(selectedButton.Text, 'average')
                           %Generate a averaging filter
                     h_average = fspecial('average',3);
                     A_average = imfilter(imgR, h_average);
                
                     imshow(A_average,'Parent',app.UIAxes_2); 
                 elseif strcmp(selectedButton.Text, 'gaussian')
                     
                     %generate a gaussian filter
                     h_gaussian = fspecial('gaussian', 3, 0.5);
                     A_gaussian = imfilter(imgR, h_gaussian);
                     imshow(A_gaussian,'Parent',app.UIAxes_2); 
                 elseif strcmp(selectedButton.Text, 'median')
                     
                       A_median = medfilt2(imgR);
                      imshow(A_median,'Parent',app.UIAxes_2); 
                 elseif strcmp(selectedButton.Text, 'guided_filter')
                     
                      A_guided_filter = imguidedfilter(imgR);
                     imshow(A_guided_filter,'Parent',app.UIAxes_2); 
                 end
             else
                 uialert(app.UIFigure,'Load an image','Invalid data');
             end
            
        end

        % Button pushed function: LoadNetButton
        function LoadNetButtonPushed(app, event)
            
            
            listing = dir('.\StoreNet\');
            
            disp(size(listing));
            
            netDir = uigetfile;
            loadNet = load(netDir);
            
            app.activeNet = loadNet.net;
            
            disp(app.activeNet);
            
            app.CurrentnetworkEditField.Value = netDir;  
             
            
        end

        % Button pushed function: LoaddatasetButton
        function LoaddatasetButtonPushed(app, event)
            
            dataSet = app.AvailabledatasetsDropDown.Value;
            targets = app.TargetsDropDown.Value;
            dataTarget = 0;
            app.DatasetEditField.Value = dataSet;
            app.TargetEditField.Value = targets;
            if dataSet == 'Leafs 1'
                app.imgSetActive = loadDataSets('.\DataSet\Folhas_1', app.pathToXls, 1, 'A2:B991');
                app.DatasetEditField.Value = dataSet;
            end
            
            if dataSet == 'Leafs 2'
                app.imgSetActive = loadDataSets('.\DataSet\Folhas_2', app.pathToXls, 1, 'A2:B991');
                app.DatasetEditField.Value = dataSet;
            end
                 
            if dataSet == 'Leafs 3'
               app.imgSetActive = loadDataSets('.\DataSet\Folhas_3', app.pathToXls, 1, 'A2:B991');
               app.DatasetEditField.Value = dataSet;
            end
            
            app.dataInput  = [app.imgSetActive.binData];
            
            if strcmp(targets, 'Species')
                dataTarget = [app.imgSetActive.speciesId];
                app.TargetEditField.Value = targets;
            end
            
            if strcmp(targets, 'SubSpecies')
                dataTarget = [app.imgSetActive.subSpeciesId];
                app.TargetEditField.Value = targets;
            end
            
            app.dataTargetBin = targetToBinary (dataTarget);
            app.loadImage = false;


        end

        % Button pushed function: LoadimageButton
        function LoadimageButtonPushed(app, event)
            
            
            imgDir =uigetfile;
            app.loadImage = true;
            
            app.DatasetEditField.Value = imgDir;
            app.TargetEditField.Value = 'Image';
            
            imshow(imgDir,'Parent',app.UIAxes);
            
            app.imgSetActive.original = imgDir;
            
            % Load the binary data to memry
            image.binData = imread(imgDir);
        
            % Conver the image to black and white
            image.binData = im2bw(image.binData, 0.5);
            
            merge = zeros(87);
            image.binData = imresize(image.binData,0.05) ;
            [y, x] = size(image.binData);
            merge(1:y, 1:x) = image.binData;
            
            image.binData = merge;
        
           image.binData = image.binData(:);
        
            app.imgSetActive.binData = image.binData;
                
           
        end

        % Button pushed function: ModifyparmsButton
        function ModifyparmsButtonPushed(app, event)
            
        end

        % Value changed function: NumoflayersSpinner
        function NumoflayersSpinnerValueChanged(app, event)
            value = app.NumoflayersSpinner.Value;
            
            if value == 1
                app.Layer1DropDown_1.Enable = 'on';
                app.Layer2DropDown.Enable = 'on';
                app.Layer3DropDown.Enable = 'off';
                app.Layer4DropDown.Enable = 'off';
                app.Layer1_size.Enable = 'on';
                app.Layer2_size.Enable = 'on';
                app.Layer3_size.Enable = 'off';
                app.Layer4_size.Enable = 'off';
            elseif value == 2
                app.Layer1DropDown_1.Enable = 'on';
                app.Layer2DropDown.Enable = 'on';
                app.Layer3DropDown.Enable = 'on';
                app.Layer4DropDown.Enable = 'off';
                app.Layer1_size.Enable = 'on';
                app.Layer2_size.Enable = 'on';
                app.Layer3_size.Enable = 'on';
                app.Layer4_size.Enable = 'off';
            elseif value == 3
                 app.Layer1DropDown_1.Enable = 'on';
                app.Layer2DropDown.Enable = 'on';
                app.Layer3DropDown.Enable = 'on';
                app.Layer4DropDown.Enable = 'on';
                app.Layer1_size.Enable = 'on';
                app.Layer2_size.Enable = 'on';
                app.Layer3_size.Enable = 'on';
                app.Layer4_size.Enable = 'on';
            else
                app.Layer1DropDown_1.Enable = 'off';
                app.Layer2DropDown.Enable = 'off';
                app.Layer3DropDown.Enable = 'off';
                app.Layer4DropDown.Enable = 'off';
                app.Layer1_size.Enable = 'off';
                app.Layer2_size.Enable = 'off';
                app.Layer3_size.Enable = 'off';
                app.Layer4_size.Enable = 'off';
            end
       
        end

        % Value changing function: NumoflayersSpinner
        function NumoflayersSpinnerValueChanging(app, event)
            changingValue = event.Value;
            
            
        end

        % Button pushed function: SavenetButton
        function SavenetButtonPushed(app, event)
            
             netName = ['net_', app.trainFcn, ];
            pathToSave = ['.\StoreNet\',netName];


          %  save(pathToSave,'activeNet');
            
            netSave = app.activeNet;
            
            uisave({'netSave'},pathToSave);
            
            
        end

        % Button pushed function: SavenetButton_2
        function SavenetButton_2Pushed(app, event)
                        
            netName = ['net_features_', app.trainFcn, ];
            pathToSave = ['.\StoreNet\',netName];
            
            netSave = app.activeNet;
            
            uisave({'netSave'},pathToSave);
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            
            
            if isempty(app.imgSetActive) 
                uialert(app.UIFigure,'Data set not found','Invalid data set');
                return
            end
            
            if isempty(app.activeNet)
                uialert(app.UIFigure,'Create or load a netWork','Invalid');
                return
            end
            
            if app.loadImage == true
                
                pathToXls = '.\DataSet\ClassificaccaoFolhas.xlsx';
                
                [leafIds, leafNames] =xlsread(pathToXls, 1, 'A2:B991');
                
                uniqueSpecies = uniqueSpeciesInVector(leafNames);
                uniqueSpeciesAndSubSpecies = uniqueSubSpeciesInVector(leafNames);
                                
                 disp(size(app.imgSetActive.binData));
                
                simOutput = sim(app.activeNet, app.imgSetActive.binData);
                
                [a b] = max(simOutput);
                app.NomedaespcieEditField.Value = char(uniqueSpecies(b));   %convertStringsToChars(uniqueSpecies(b));
                
            else
                simOutput = sim(app.activeNet, app.dataInput); 
                r=0;% CHANGE LINE add R=0;
                for i=1:size(simOutput,2)               % Para cada classificacao  
                       %runs   
                      [a b] = max(simOutput(:,i));          %b guarda a linha onde encontrou valor mais alto da saida obtida
                      [c d] = max(app.dataTargetBin(:,i));  %d guarda a linha onde encontrou valor mais alto da saida desejada
                      if b == d                              % se estao na mesma linha, a classificacao foi correta (incrementa 1)
                          r = r+1;
                      end
                end
                
                accuracyPred = r/size(simOutput,2)*100;
                app.precisao.Value = char(num2str(accuracyPred)); % CHANGE LINE AND CHAR
                disp(accuracyPred)
            
            end
            
        end

        % Button pushed function: SimulateButton_2
        function SimulateButton_2Pushed(app, event)
                 
            if isempty(app.imgSetActive) 
                uialert(app.UIFigure,'Data set not found','Invalid data set');
                return
            end
            
            if isempty(app.activeNet)
                uialert(app.UIFigure,'Create or load a netWork','Invalid');
                return
            end
            
            if app.loadImage == true
                
                pathToXls = '.\DataSet\ClassificaccaoFolhas.xlsx';
                
                [leafIds, leafNames] =xlsread(pathToXls, 1, 'A2:B991');
                
                uniqueSpecies = uniqueSpeciesInVector(leafNames);
                uniqueSpeciesAndSubSpecies = uniqueSubSpeciesInVector(leafNames);
                                
                simOutput = sim(app.activeNet, app.imgSetActive.binData);
                
                [a b] = max(simOutput);
                app.NomedaespcieEditField.Value = char(uniqueSpecies(b));   %convertStringsToChars(uniqueSpecies(b));
                
            else

                simOutput = sim(app.activeNet, app.dataInputFeatures); 

            end
                r=0;% CHANGE LINE add R=0;
                for i=1:size(simOutput,2)               % Para cada classificacao  
                       %runs   
                      [a b] = max(simOutput(:,i));          %b guarda a linha onde encontrou valor mais alto da saida obtida
                      [c d] = max(app.dataTargetBin(:,i));  %d guarda a linha onde encontrou valor mais alto da saida desejada
                      if b == d                              % se estao na mesma linha, a classificacao foi correta (incrementa 1)
                          r = r+1;
                      end
                end
                
                accuracyPred = r/size(simOutput,2)*100;
                app.precisao.Value = char(num2str(accuracyPred)); % CHANGE LINE AND CHAR
                disp(accuracyPred)
  
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            
            
            if isempty(app.imgSetActive) 
                
                uialert(app.UIFigure,'Data set not found','Invalid data set');
                return
            
            end
            
            if isempty(app.activeNet) 
                
                uialert(app.UIFigure,'Create or load a NN','Invalid NN');
                return
            
            end
            
            dataInput =  app.dataInput;
            dataTargetBin = app.dataTargetBin;
            
            
            [app.activeNet,app.tr] = train(app.activeNet, dataInput, dataTargetBin);
            
            app.simOut = sim(app.activeNet, dataInput);
              
            
    r=0;
    for i=1:size(app.simOut,2)               % Para cada classificacao  
       %runs   
      [a b] = max(app.simOut(:,i));          %b guarda a linha onde encontrou valor mais alto da saida obtida
      [c d] = max(dataTargetBin(:,i));  %d guarda a linha onde encontrou valor mais alto da saida desejada
      if b == d                       % se estao na mesma linha, a classificacao foi correta (incrementa 1)
          r = r+1;
      end
    end
            
    accuracyPred = r/size(app.simOut,2)*100;
    perf_train = perform(app.activeNet, dataTargetBin, app.simOut); 
    
    
    err_test = 0;
    accuracyTrain = 0;
    perf_test = 0;
    outTest = 0;
    TTargets = 0;
    
    if ~strcmp(app.DividefunctionDropDown.Value, 'none')
            
        TInput = dataInput(:, app.tr.testInd);
        TTargets = dataTargetBin(:, app.tr.testInd);
            
        outTest = sim(app.activeNet, TInput); % CHANGE SIM
            
        %Calcula e mostra a percentagem de classificacoes corretas no conjunto de teste
        r=0;
        for i=1:size(app.tr.testInd,2)              % Para cada classificacao  
          [a b] = max(outTest(:,i));                %b guarda a linha onde encontrou valor mais alto da saida obtida
          [c d] = max(TTargets(:,i));           %d guarda a linha onde encontrou valor mais alto da saida desejada
          if b == d                             % se estao na mesma linha, a classificacao foi correta (incrementa 1)
              r = r+1;
          end
        end
        accuracyTrain = r/size(app.tr.testInd,2)*100;
        ERR = outTest - TTargets;
        err_test = mse(ERR);
        perf_test = perform(app.activeNet, TTargets, outTest); 
    end


    app.bestEpoch.Value        = app.tr.best_epoch;
    app.time.Value             = (app.tr.time(app.tr.num_epochs)*1000)
    
    app.pTest.Value            = accuracyTrain;
    app.pTrain.Value           = accuracyPred;
    app.perf.Value             = perf_train;
    app.bestTrainPerf.Value    = app.tr.best_perf;
    app.bestPerfVal.Value      = app.tr.best_vperf;
    app.bestPerfTest.Value     = app.tr.best_tperf;
            
    view(app.activeNet);
            
            
        end

        % Button pushed function: TrainButton_2
        function TrainButton_2Pushed(app, event)
            
        
        if isempty(app.imgSetActive) 
            uialert(app.UIFigure,'Data set not found','Invalid data set');
            return
        end
        
        if isempty(app.activeNet) 
            uialert(app.UIFigure,'Create or load a NN','Invalid NN');
            return
        end
        
        dataTargetBin = app.dataTargetBin;
        dataInput  = [app.imgSetActive.charcAsVec];
            
        app.dataInputFeatures = dataInput;
        
        app.activeNet.inputs{1}.size = 8;

        
        [app.activeNet,app.tr] = train(app.activeNet, dataInput, dataTargetBin);
            
        app.simOut = sim(app.activeNet, dataInput);
            
            
        r=0;
        for i=1:size(app.simOut,2)               % Para cada classificacao  
           %runs   
          [a b] = max(app.simOut(:,i));          %b guarda a linha onde encontrou valor mais alto da saida obtida
          [c d] = max(dataTargetBin(:,i));  %d guarda a linha onde encontrou valor mais alto da saida desejada
          if b == d                       % se estao na mesma linha, a classificacao foi correta (incrementa 1)
              r = r+1;
          end
        end
                
        accuracyPred = r/size(app.simOut,2)*100;
        perf_train = perform(app.activeNet, dataTargetBin, app.simOut); 
        
        
        err_test = 0;
        accuracyTrain = 0;
        perf_test = 0;
        outTest = 0;
        TTargets = 0;
        
        if ~strcmp(app.DividefunctionDropDown.Value, 'none')
                
            TInput = dataInput(:, app.tr.testInd);
            TTargets = dataTargetBin(:, app.tr.testInd);
                
            outTest = sim(app.activeNet, TInput); % CHANGE SIM
                
            %Calcula e mostra a percentagem de classificacoes corretas no conjunto de teste
            r=0;
            for i=1:size(app.tr.testInd,2)              % Para cada classificacao  
              [a b] = max(outTest(:,i));                %b guarda a linha onde encontrou valor mais alto da saida obtida
              [c d] = max(TTargets(:,i));           %d guarda a linha onde encontrou valor mais alto da saida desejada
              if b == d                             % se estao na mesma linha, a classificacao foi correta (incrementa 1)
                  r = r+1;
              end
            end
            accuracyTrain = r/size(app.tr.testInd,2)*100;
            ERR = outTest - TTargets;
            err_test = mse(ERR);
            perf_test = perform(app.activeNet, TTargets, outTest); 
        end


        app.bestEpoch.Value        = app.tr.best_epoch;
        app.time.Value             = (app.tr.time(app.tr.num_epochs)*1000)
        
        app.pTest.Value            = accuracyTrain;
        app.pTrain.Value           = accuracyPred;
        app.perf.Value             = perf_train;
        app.bestTrainPerf.Value    = app.tr.best_perf;
        app.bestPerfVal.Value      = app.tr.best_vperf;
        app.bestPerfTest.Value     = app.tr.best_tperf;
        
        app.precisao_img_treino.Value      = accuracyTrain;
        app.precisao_img_test.Value    = accuracyPred;
        
        view(app.activeNet);
            
        end

        % Button pushed function: StartDetectionButton
        function StartDetectionButtonPushed(app, event)
            %Create a camera board object by executing the following command on the MATLAB prompt.

            faceDetect = false;
            app.camOn = true;
        %NN
        
            if isempty(app.activeNet)
                uialert(app.UIFigure,'Create or load a netWork','Invalid');
                return
            end
        
            pathToXls = '.\DataSet\ClassificaccaoFolhas.xlsx';
            
            [leafIds, leafNames] =xlsread(pathToXls, 1, 'A2:B991');
            
            uniqueSpecies = uniqueSpeciesInVector(leafNames);
            uniqueSpeciesAndSubSpecies = uniqueSubSpeciesInVector(leafNames);
        %END

        
        try
        
        
           if app.camLaptop
               cam = webcam;
               cam.Resolution = '320x240';
               disp(app.camLaptop);
           else
                  rpi = raspi(app.IPEditField.Value,'pi','raspberry')
                cam = cameraboard(rpi,'Resolution','320x240','FrameRate',30);
           end
            
        catch exp
            disp(exp);
            return
        end

        %The cam is a handle to a cameraboard object. Let's display the images captured from Raspberry Pi Camera Board in MATLAB.
        
        while(app.camOn)
            
            try
                img = snapshot(cam);
            catch ex
                disp(ex);
                continue;
            end
        
           if faceDetect
              %face detect
   
  
             fD = vision.CascadeObjectDetector();
             bbox = step(fD, img);
             imageOut = insertObjectAnnotation(img,'rectangle',bbox,'Face');
           
            imshow(imageOut,'Parent',app.UIAxes_video);
    
            else
            
            %subtract the red component from the grayscale image
            %to exract the red components
            diff_img = imsubtract(img(:,:,1),rgb2gray(img));
            %use a median filter to filter out noise
            diff_img = medfilt2(diff_img,[3,3]);
            %convert image to binary
            diff_img = imbinarize(diff_img,0.18);
            %remove all those pixels less than 300px
            diff_img = bwareaopen(diff_img,300);
            
            %now we can draw rectangular boxes around
            %the red object detected
            
            %label all the connected components in the image
            bw = bwlabel(diff_img,8);
            %here we do the image analysis.We get a set of properties
            %for each labeled region
            
            stats = regionprops(bw,'BoundingBox','Centroid');
            
            imshow(img,'Parent',app.UIAxes_video);
      
        
            for obj=1:length(stats)
                %now save the centrod and bounding box vlaues in
                %variable bc,bb
                bb=stats(obj).BoundingBox;
                bc=stats(obj).Centroid;
                
                croppedImage = imcrop(img, bb);
                imshow(croppedImage);
                %NN
  
                image.binData = im2bw(croppedImage, 0.5);
                merge = zeros(87);
                image.binData = imresize(image.binData,0.05) ;
                [y, x] = size(image.binData);
                merge(1:y, 1:x) = image.binData;

                merge = merge(:);
                
                simOutput = sim(app.activeNet, merge);
                [a b] = max(simOutput);
                app.NomedaespcieEditField_2.Value = char(uniqueSpecies(b)); 
            
                 %endNN

                %so we use a rectangle function to draw rectangles
                %with data from variables bb,bc

                rectangle(app.UIAxes_video,'Position',bb,'EdgeColor','r','LineWidth',2)
%                 hold on;
%                 plot(app.UIAxes_video,bc(1),bc(2),'-m+');
%                 hold on;
                end
           end
            % drawnow;
            hold off;
        end

     disp('Clear conn');
   
     clear cam;
     clear mypi;

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.camOn = false;
        end

        % Button pushed function: LoadimageButton_2
        function LoadimageButton_2Pushed(app, event)
             imgDir =uigetfile;
            app.loadImage = true;
            
            app.DatasetEditField.Value = imgDir;
            app.TargetEditField.Value = 'Image';
            
            imshow(imgDir,'Parent',app.UIAxes_2);

            try
                
            app.imgSetActive.original = imgDir;
            
            catch exp
                
                
            end
            
            % Load the binary data to memry
            image.binData = imread(imgDir);
        
            % Conver the image to black and white
            image.binData = im2bw(image.binData, 0.5);
            
            merge = zeros(87);
            image.binData = imresize(image.binData,0.05) ;
            [y, x] = size(image.binData);
            merge(1:y, 1:x) = image.binData;
            
            image.binData = merge;
        
          % image.binData = image.binData(:);
        
            app.imgSetActive.binData = image.binData;
                
        end

        % Selection changed function: VideoSourceButtonGroup
        function VideoSourceButtonGroupSelectionChanged(app, event)
            selectedButton = app.VideoSourceButtonGroup.SelectedObject;
          
            if strcmp(selectButton.Text, 'laptop cam')
                camLaptop = true;
                disp(camLaptop);
            else
                camLaptop = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigNNTab = uitab(app.TabGroup);
            app.ConfigNNTab.Title = 'Config NN';
            app.NumoflayersSpinnerLabel = uilabel(app.ConfigNNTab);
            app.NumoflayersSpinnerLabel.Text = 'Num of  layers';
            app.NumoflayersSpinner = uispinner(app.ConfigNNTab);
            app.NumoflayersSpinner.ValueChangingFcn = createCallbackFcn(app, @NumoflayersSpinnerValueChanging, true);
            app.NumoflayersSpinner.Limits = [0 3];
            app.NumoflayersSpinner.ValueChangedFcn = createCallbackFcn(app, @NumoflayersSpinnerValueChanged, true);
            app.TrainfunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.TrainfunctionDropDownLabel.Text = 'Train function';
            app.TrainfunctionDropDown = uidropdown(app.ConfigNNTab);
            app.TrainfunctionDropDown.Items = {'traingdx', 'trainscg', 'trainoss', 'traingda', 'traingdm', 'trainrp', 'trainlm', 'traincgp', 'traincgf', 'traincgb', 'trainc', 'trainbu', 'trainbr', 'trainbfgc', 'trainbfg'};
            app.TrainfunctionDropDown.Value = 'traingdx';
            app.PerformancefunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.PerformancefunctionDropDownLabel.Text = 'Performance function';
            app.PerformancefunctionDropDown = uidropdown(app.ConfigNNTab);
            app.PerformancefunctionDropDown.Items = {'mse', 'Option 2', 'Option 3', 'Option 4'};
            app.PerformancefunctionDropDown.Value = 'mse';
            app.StopconditionsPanel = uipanel(app.ConfigNNTab);
            app.StopconditionsPanel.Title = 'Stop conditions';
            app.epochsEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.epochsEditFieldLabel.Text = 'epochs';
            app.epochsEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.epochsEditField.Value = '1000';
            app.min_gradEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.min_gradEditFieldLabel.Text = 'min_grad';
            app.min_gradEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.min_gradEditField.Value = '1.e-200';
            app.validationsEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.validationsEditFieldLabel.Text = 'validations';
            app.validationsEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.validationsEditField.Value = '1000000';
            app.timeEditFieldLabel = uilabel(app.StopconditionsPanel);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.StopconditionsPanel, 'text');
            app.timeEditField.Value = 'inf';
            app.AdvanceconfigurationPanel = uipanel(app.ConfigNNTab);
            app.AdvanceconfigurationPanel.Title = 'Advance configuration';
            app.learningrateEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.learningrateEditFieldLabel.Text = 'learning rate';
            app.learningrateEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.learningrateEditField.Value = '0.01';
            app.momentumEditField_2Label = uilabel(app.AdvanceconfigurationPanel);
            app.momentumEditField_2Label.Text = 'momentum';
            app.momentumEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.momentumEditField.Value = '0.7';
            app.lambdaEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.lambdaEditFieldLabel.Text = 'lambda';
            app.lambdaEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.lambdaEditField.Value = '5.0e-7';
            app.sigmaEditFieldLabel = uilabel(app.AdvanceconfigurationPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.AdvanceconfigurationPanel, 'text');
            app.sigmaEditField.Value = '5.0e-5';
            app.CreateButton = uibutton(app.ConfigNNTab, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.LayerstranferfunctionPanel = uipanel(app.ConfigNNTab);
            app.LayerstranferfunctionPanel.Title = 'Layers tranfer function';
            app.Layer1DropDown_7Label = uilabel(app.LayerstranferfunctionPanel);
            app.Layer1DropDown_7Label.Text = 'Layer  1';
            app.Layer1DropDown_1 = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer1DropDown_1.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer1DropDown_1.Enable = 'off';
            app.Layer1DropDown_1.Value = 'logsig';
            app.NumberofneuronsLabel = uilabel(app.LayerstranferfunctionPanel);
            app.NumberofneuronsLabel.Text = 'Number of neurons';
            app.Layer1_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer1_size.Limits = [0 Inf];
            app.Layer1_size.Enable = 'off';
            app.Layer1_size.Value = 10;
            app.Layer2_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer2_size.Limits = [0 Inf];
            app.Layer2_size.Enable = 'off';
            app.Layer2_size.Value = 10;
            app.Layer3_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer3_size.Limits = [0 Inf];
            app.Layer3_size.Enable = 'off';
            app.Layer3_size.Value = 10;
            app.Layer2DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer2DropDownLabel.Text = 'Layer  2';
            app.Layer2DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer2DropDown.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer2DropDown.Enable = 'off';
            app.Layer2DropDown.Value = 'logsig';
            app.Layer3DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer3DropDownLabel.Text = 'Layer  3';
            app.Layer3DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer3DropDown.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer3DropDown.Enable = 'off';
            app.Layer3DropDown.Value = 'logsig';
            app.Layer4_size = uieditfield(app.LayerstranferfunctionPanel, 'numeric');
            app.Layer4_size.Limits = [0 Inf];
            app.Layer4_size.Enable = 'off';
            app.Layer4_size.Value = 10;
            app.Layer4DropDownLabel = uilabel(app.LayerstranferfunctionPanel);
            app.Layer4DropDownLabel.Text = 'Layer  4';
            app.Layer4DropDown = uidropdown(app.LayerstranferfunctionPanel);
            app.Layer4DropDown.Items = {'logsig', 'tansig', 'softmax', 'compet', 'hardlim', 'hardlims', 'netinv', 'poslin', 'purelin', 'radbas', 'radbasn', 'satlin', 'satlins', 'tribas'};
            app.Layer4DropDown.Enable = 'off';
            app.Layer4DropDown.Value = 'logsig';
            app.DividefunctionDropDownLabel = uilabel(app.ConfigNNTab);
            app.DividefunctionDropDownLabel.Text = 'Divide function';
            app.DividefunctionDropDown = uidropdown(app.ConfigNNTab);
            app.DividefunctionDropDown.Items = {'none', 'dividerand', 'divideblock', 'divideint', 'divideind'};
            app.DividefunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @DividefunctionDropDownValueChanged, true);
            app.DividefunctionDropDown.Value = 'none';
            app.ModifyparmsButton = uibutton(app.ConfigNNTab, 'push');
            app.ModifyparmsButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyparmsButtonPushed, true);
            app.ModifyparmsButton.Text = 'Modify parms';
            app.trainRatio = uieditfield(app.ConfigNNTab, 'numeric');
            app.trainRatio.Limits = [0 Inf];
            app.trainRatio.Enable = 'off';
            app.trainRatio.Value = 0.7;
            app.valRatio = uieditfield(app.ConfigNNTab, 'numeric');
            app.valRatio.Limits = [0 Inf];
            app.valRatio.Enable = 'off';
            app.valRatio.Value = 0.15;
            app.testRatio = uieditfield(app.ConfigNNTab, 'numeric');
            app.testRatio.Limits = [0 Inf];
            app.testRatio.Enable = 'off';
            app.testRatio.Value = 0.15;
            app.TrainRatioLabel = uilabel(app.ConfigNNTab);
            app.TrainRatioLabel.Text = 'Train Ratio';
            app.ValRatioLabel = uilabel(app.ConfigNNTab);
            app.ValRatioLabel.Text = 'Val Ratio';
            app.TestRatioLabel = uilabel(app.ConfigNNTab);
            app.TestRatioLabel.Text = 'Test Ratio';
            app.TrainnetworkTab = uitab(app.TabGroup);
            app.TrainnetworkTab.Title = 'Train network';
            app.TrainButton = uibutton(app.TrainnetworkTab, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.ResultsPanel = uipanel(app.TrainnetworkTab);
            app.ResultsPanel.Title = 'Results';
            app.pTest = uieditfield(app.ResultsPanel, 'numeric');
            app.pTest.Limits = [0 Inf];
            app.pTest.Editable = 'off';
            app.PrecisotreinoLabel = uilabel(app.ResultsPanel);
            app.PrecisotreinoLabel.Text = 'Precisão treino';
            app.PrecisotesteLabel = uilabel(app.ResultsPanel);
            app.PrecisotesteLabel.Text = 'Preciso teste';
            app.pTrain = uieditfield(app.ResultsPanel, 'numeric');
            app.pTrain.Limits = [0 Inf];
            app.pTrain.Editable = 'off';
            app.bestTrainPerf = uieditfield(app.ResultsPanel, 'numeric');
            app.bestTrainPerf.Limits = [0 Inf];
            app.bestTrainPerf.Editable = 'off';
            app.MelhorperformancetreinoLabel = uilabel(app.ResultsPanel);
            app.MelhorperformancetreinoLabel.Text = 'Melhor performance treino';
            app.bestPerfVal = uieditfield(app.ResultsPanel, 'numeric');
            app.bestPerfVal.Limits = [0 Inf];
            app.bestPerfVal.Editable = 'off';
            app.MelhorperformancevalidaoLabel = uilabel(app.ResultsPanel);
            app.MelhorperformancevalidaoLabel.Text = 'Melhor performance validação';
            app.bestPerfTest = uieditfield(app.ResultsPanel, 'numeric');
            app.bestPerfTest.Limits = [0 Inf];
            app.bestPerfTest.Editable = 'off';
            app.MelhorperformancetesteLabel = uilabel(app.ResultsPanel);
            app.MelhorperformancetesteLabel.Text = 'Melhor performance teste';
            app.MelhopocaLabel = uilabel(app.ResultsPanel);
            app.MelhopocaLabel.Text = 'Melho época';
            app.bestEpoch = uieditfield(app.ResultsPanel, 'numeric');
            app.bestEpoch.Limits = [0 Inf];
            app.bestEpoch.Editable = 'off';
            app.TempoLabel = uilabel(app.ResultsPanel);
            app.TempoLabel.Text = 'Tempo';
            app.time = uieditfield(app.ResultsPanel, 'numeric');
            app.time.Limits = [0 Inf];
            app.time.Editable = 'off';
            app.perf = uieditfield(app.ResultsPanel, 'numeric');
            app.perf.Limits = [0 Inf];
            app.perf.Editable = 'off';
            app.PerformanceLabel = uilabel(app.ResultsPanel);
            app.PerformanceLabel.Text = 'Performance';
            app.SavenetButton = uibutton(app.TrainnetworkTab, 'push');
            app.SavenetButton.ButtonPushedFcn = createCallbackFcn(app, @SavenetButtonPushed, true);
            app.SavenetButton.Text = 'Save net';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.LoadimageButton = uibutton(app.SimulateTab, 'push');
            app.LoadimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButtonPushed, true);
            app.LoadimageButton.Text = 'Load image';
            app.SimulateButton = uibutton(app.SimulateTab, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.UIAxes = uiaxes(app.SimulateTab);
            title(app.UIAxes, 'Folha')
            app.Label_2 = uilabel(app.SimulateTab);
            app.Label_2.Text = '';
            app.NomedaespcieEditFieldLabel = uilabel(app.SimulateTab);
            app.NomedaespcieEditFieldLabel.Text = 'Nome da espécie';
            app.NomedaespcieEditField = uieditfield(app.SimulateTab, 'text');
            app.NomedaespcieEditField.Editable = 'off';
            app.PrecisoLabel = uilabel(app.SimulateTab);
            app.PrecisoLabel.Text = 'Precisão';
            app.precisao = uieditfield(app.SimulateTab, 'text');
            app.precisao.Editable = 'off';
            app.ImageFeaturesTab = uitab(app.TabGroup);
            app.ImageFeaturesTab.Title = 'ImageFeatures';
            app.TrainButton_2 = uibutton(app.ImageFeaturesTab, 'push');
            app.TrainButton_2.ButtonPushedFcn = createCallbackFcn(app, @TrainButton_2Pushed, true);
            app.TrainButton_2.Text = 'Train';
            app.UIAxes_2 = uiaxes(app.ImageFeaturesTab);
            title(app.UIAxes_2, 'Folha')
            app.NomedaespcieEditField_3Label = uilabel(app.ImageFeaturesTab);
            app.NomedaespcieEditField_3Label.Text = 'Nome da espécie';
            app.NomedaespcieEditField_3 = uieditfield(app.ImageFeaturesTab, 'text');
            app.NomedaespcieEditField_3.Editable = 'off';
            app.ExtractfeaturesButton = uibutton(app.ImageFeaturesTab, 'push');
            app.ExtractfeaturesButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractfeaturesButtonPushed, true);
            app.ExtractfeaturesButton.Text = 'Extract features';
            app.featuresPanel = uipanel(app.ImageFeaturesTab);
            app.featuresPanel.Title = 'features';
            app.height = uieditfield(app.featuresPanel, 'numeric');
            app.height.Limits = [0 Inf];
            app.height.Editable = 'off';
            app.widthLabel = uilabel(app.featuresPanel);
            app.widthLabel.Text = 'width';
            app.heightLabel = uilabel(app.featuresPanel);
            app.heightLabel.Text = 'height';
            app.width = uieditfield(app.featuresPanel, 'numeric');
            app.width.Limits = [0 Inf];
            app.width.Editable = 'off';
            app.area = uieditfield(app.featuresPanel, 'numeric');
            app.area.Limits = [0 Inf];
            app.area.Editable = 'off';
            app.AreaLabel = uilabel(app.featuresPanel);
            app.AreaLabel.Text = 'Area';
            app.corners = uieditfield(app.featuresPanel, 'numeric');
            app.corners.Limits = [0 Inf];
            app.corners.Editable = 'off';
            app.CornersLabel = uilabel(app.featuresPanel);
            app.CornersLabel.Text = 'Corners';
            app.perimeter = uieditfield(app.featuresPanel, 'numeric');
            app.perimeter.Limits = [0 Inf];
            app.perimeter.Editable = 'off';
            app.PerimeterLabel = uilabel(app.featuresPanel);
            app.PerimeterLabel.Text = 'Perimeter';
            app.ori = uieditfield(app.featuresPanel, 'numeric');
            app.ori.Limits = [0 Inf];
            app.ori.Editable = 'off';
            app.OrientationLabel = uilabel(app.featuresPanel);
            app.OrientationLabel.Text = 'Orientation';
            app.SimulateButton_2 = uibutton(app.ImageFeaturesTab, 'push');
            app.SimulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton_2Pushed, true);
            app.SimulateButton_2.Text = 'Simulate';
            app.ImagefiltersButtonGroup = uibuttongroup(app.ImageFeaturesTab);
            app.ImagefiltersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImagefiltersButtonGroupSelectionChanged, true);
            app.ImagefiltersButtonGroup.Title = 'Image filters';
            app.averageButton = uiradiobutton(app.ImagefiltersButtonGroup);
            app.averageButton.Text = 'average';
            app.gaussianButton = uiradiobutton(app.ImagefiltersButtonGroup);
            app.gaussianButton.Text = 'gaussian';
            app.medianButton = uiradiobutton(app.ImagefiltersButtonGroup);
            app.medianButton.Text = 'median';
            app.guided_filterButton = uiradiobutton(app.ImagefiltersButtonGroup);
            app.guided_filterButton.Text = 'guided_filter';
            app.originalButton = uiradiobutton(app.ImagefiltersButtonGroup);
            app.originalButton.Text = 'original';
            app.originalButton.Value = true;
            app.SavenetButton_2 = uibutton(app.ImageFeaturesTab, 'push');
            app.SavenetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavenetButton_2Pushed, true);
            app.SavenetButton_2.Text = 'Save net';
            app.LoadimageButton_2 = uibutton(app.ImageFeaturesTab, 'push');
            app.LoadimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadimageButton_2Pushed, true);
            app.LoadimageButton_2.Text = 'Load image';
            app.PrecisotreinoEditFieldLabel = uilabel(app.ImageFeaturesTab);
            app.PrecisotreinoEditFieldLabel.Text = 'Precisão treino';
            app.precisao_img_treino = uieditfield(app.ImageFeaturesTab, 'numeric');
            app.PrecisotesteEditFieldLabel = uilabel(app.ImageFeaturesTab);
            app.PrecisotesteEditFieldLabel.Text = 'Precisão teste';
            app.precisao_img_test = uieditfield(app.ImageFeaturesTab, 'numeric');
            app.RealTimeTab = uitab(app.TabGroup);
            app.RealTimeTab.Title = 'RealTime';
            app.UIAxes_video = uiaxes(app.RealTimeTab);
            title(app.UIAxes_video, 'Video')
            app.StartDetectionButton = uibutton(app.RealTimeTab, 'push');
            app.StartDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartDetectionButtonPushed, true);
            app.StartDetectionButton.Text = 'Start Detection';
            app.VideoSourceButtonGroup = uibuttongroup(app.RealTimeTab);
            app.VideoSourceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VideoSourceButtonGroupSelectionChanged, true);
            app.VideoSourceButtonGroup.Title = 'Video Source';
            app.laptopcamButton = uiradiobutton(app.VideoSourceButtonGroup);
            app.laptopcamButton.Text = 'laptop cam';
            app.laptopcamButton.Value = true;
            app.usbcamButton = uiradiobutton(app.VideoSourceButtonGroup);
            app.usbcamButton.Text = 'usb cam';
            app.rpicamButton = uiradiobutton(app.VideoSourceButtonGroup);
            app.rpicamButton.Text = 'rpi cam';
            app.NomedaespcieEditField_2Label = uilabel(app.RealTimeTab);
            app.NomedaespcieEditField_2Label.Text = 'Nome da espécie';
            app.NomedaespcieEditField_2 = uieditfield(app.RealTimeTab, 'text');
            app.NomedaespcieEditField_2.Editable = 'off';
            app.IPEditFieldLabel = uilabel(app.RealTimeTab);
            app.IPEditFieldLabel.Text = 'IP';
            app.IPEditField = uieditfield(app.RealTimeTab, 'text');
            app.IPEditField.Value = '192.168.137.158';
            app.StopButton = uibutton(app.RealTimeTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CurrentnetworkEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentnetworkEditFieldLabel.Text = 'Current network';
            app.CurrentnetworkEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentnetworkEditField.Editable = 'off';
            app.LoaddatasetButton = uibutton(app.UIFigure, 'push');
            app.LoaddatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddatasetButtonPushed, true);
            app.LoaddatasetButton.Text = 'Load data set';
            app.DatasetEditFieldLabel = uilabel(app.UIFigure);
            app.DatasetEditFieldLabel.Text = 'Data set';
            app.DatasetEditField = uieditfield(app.UIFigure, 'text');
            app.DatasetEditField.Editable = 'off';
            app.AvailabledatasetsDropDownLabel = uilabel(app.UIFigure);
            app.AvailabledatasetsDropDownLabel.Text = 'Available data sets';
            app.AvailabledatasetsDropDown = uidropdown(app.UIFigure);
            app.AvailabledatasetsDropDown.Items = {'Leafs 1', 'Leafs 2', 'Leafs 3'};
            app.AvailabledatasetsDropDown.Value = 'Leafs 1';
            app.TargetEditFieldLabel = uilabel(app.UIFigure);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.UIFigure, 'text');
            app.TargetEditField.Editable = 'off';
            app.TargetsDropDownLabel = uilabel(app.UIFigure);
            app.TargetsDropDownLabel.Text = 'Targets';
            app.TargetsDropDown = uidropdown(app.UIFigure);
            app.TargetsDropDown.Items = {'Species', 'SubSpecies'};
            app.TargetsDropDown.Value = 'Species';
            app.LoadNetButton = uibutton(app.UIFigure, 'push');
            app.LoadNetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetButtonPushed, true);
            app.LoadNetButton.Text = 'Load Net';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end